classdef PreprocessEphysGUI < matlab.apps.AppBase
    %PREPROCESSEPHYSGUI App to preprocess ephys data.
    %   Merge data from a sequence of recordings, median filter the data,
    %   and save out a single merged .bin file.
    %   
    %   Use
    %   
    %   1. Select the probe model. This requires that the app be in the
    %   expected position relative to the repository, and will search for a
    %   .json in the repo when the app is started.
    %   2. Check boxes to change options for the raw data's data type and
    %   if you want to apply the median filter. These are both True by
    %   default.
    %   3. Click the button to add a recording. Select the ephys .bin file
    %   for an individual recording (e.g., in the 'hf1_wn' directory).
    %   Continue to add recordings until they are all listed in the text
    %   box.
    %   4. Select the path to write a new .bin file of the merged and
    %   filtered data. In general, this should be the animal's directory
    %   above all individual recording folders.
    %   5. Click the button to start preprocessing. Keep the window open
    %   until the process is done.
    %   
    %   Niell lab - FreelyMovingEphys
    %   Written by DMM, Nov 2022

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessEphysUIFigure      matlab.ui.Figure
        PreprocessButton             matlab.ui.control.Button
        savePathWindow               matlab.ui.control.TextArea
        PicksavepathButton           matlab.ui.control.Button
        RecordingsListBox            matlab.ui.control.ListBox
        AddrecordingButton           matlab.ui.control.Button
        Rawdataistypeuint16CheckBox  matlab.ui.control.CheckBox
        MedianfilterCheckBox         matlab.ui.control.CheckBox
        ProbeDropDown                matlab.ui.control.DropDown
        ProbeDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        fileList
        pathList
        nf = 1
        outputFilename
        nCh
        probe
        probeMap
        doMedian
        subset
        isUint16
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add a few files to the current path
            % folder of matlab utils
            addpath('utils');
            % json of probe maps
            filePath = matlab.desktop.editor.getActiveFilename;
            pathparts = strsplit(filePath,filesep);
            repoPath = strjoin(pathparts(1:end-3),filesep);
            probe_json_path = [repoPath,filesep,'fmEphys',filesep,'utils',filesep,'probes.json'];
            addpath(probe_json_path);
            
            % List possible ephys probes (read from json)
            app.ProbeDropDown.Items = listProbes;

        end

        % Button pushed function: PicksavepathButton
        function pickSavepath(app, event)
            [f, p] = uiputfile('*.bin', 'Pick path for output file.');
            app.outputFilename = fullfile(p, f);
            app.savePathWindow.Value = f;

        end

        % Button pushed function: PreprocessButton
        function startAnalysis(app, event)
            applyMedianFilt(app.nCh, app.doMedian, app.subset, app.isUint16, app.probeMap, 0);
            delete(app);

        end

        % Button pushed function: AddrecordingButton
        function addRecoding(app, event)
            [f, p] = uigetfile('*.bin', 'Pick next binary file.');
            app.fileList{app.nf} = f;
            app.pathList{app.nf} = p;
            app.RecordingsListBox.Items = app.fileList;
            app.nf = app.nf + 1;

        end

        % Value changed function: ProbeDropDown
        function setProbe(app, event)
            app.probe = app.ProbeDropDown.Value;
            [app.probeMap, app.nCh, app.subset] = getProbeMap(app.probe);
            
        end

        % Value changed function: MedianfilterCheckBox
        function setMedianOpt(app, event)
            app.doMedian = app.MedianfilterCheckBox.Value;
            
        end

        % Value changed function: Rawdataistypeuint16CheckBox
        function setDataTypeOpt(app, event)
            app.isUint16 = app.Rawdataistypeuint16CheckBox.Value;
            
        end

        % Close request function: PreprocessEphysUIFigure
        function PreprocessEphysUIFigureCloseRequest(app, event)
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessEphysUIFigure = uifigure('Visible', 'off');
            app.PreprocessEphysUIFigure.Name = 'PreprocessEphys';
            app.PreprocessEphysUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreprocessEphysUIFigureCloseRequest, true);
            app.ProbeDropDownLabel = uilabel(app.PreprocessEphysUIFigure);
            app.ProbeDropDownLabel.Text = 'Probe';
            app.ProbeDropDown = uidropdown(app.PreprocessEphysUIFigure);
            app.ProbeDropDown.Items = {};
            app.ProbeDropDown.ValueChangedFcn = createCallbackFcn(app, @setProbe, true);
            app.ProbeDropDown.Tag = 'probeName';
            app.ProbeDropDown.Value = {};
            app.MedianfilterCheckBox = uicheckbox(app.PreprocessEphysUIFigure);
            app.MedianfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @setMedianOpt, true);
            app.MedianfilterCheckBox.Tag = 'doMed';
            app.MedianfilterCheckBox.Text = 'Median filter';
            app.MedianfilterCheckBox.Value = true;
            app.Rawdataistypeuint16CheckBox = uicheckbox(app.PreprocessEphysUIFigure);
            app.Rawdataistypeuint16CheckBox.ValueChangedFcn = createCallbackFcn(app, @setDataTypeOpt, true);
            app.Rawdataistypeuint16CheckBox.Tag = 'doMed';
            app.Rawdataistypeuint16CheckBox.Text = 'Raw data is type uint16';
            app.Rawdataistypeuint16CheckBox.Value = true;
            app.AddrecordingButton = uibutton(app.PreprocessEphysUIFigure, 'push');
            app.AddrecordingButton.ButtonPushedFcn = createCallbackFcn(app, @addRecoding, true);
            app.AddrecordingButton.Text = 'Add recording';
            app.RecordingsListBox = uilistbox(app.PreprocessEphysUIFigure);
            app.RecordingsListBox.Items = {};
            app.RecordingsListBox.Value = {};
            app.PicksavepathButton = uibutton(app.PreprocessEphysUIFigure, 'push');
            app.PicksavepathButton.ButtonPushedFcn = createCallbackFcn(app, @pickSavepath, true);
            app.PicksavepathButton.Text = 'Pick save path';
            app.savePathWindow = uitextarea(app.PreprocessEphysUIFigure);
            app.PreprocessButton = uibutton(app.PreprocessEphysUIFigure, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @startAnalysis, true);
            app.PreprocessButton.Text = 'Preprocess';
            % Show the figure after all components are created
            app.PreprocessEphysUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessEphysGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreprocessEphysUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PreprocessEphysUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessEphysUIFigure)
        end
    end
end